classdef TransitorioBifasico < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        MainGrid           matlab.ui.container.GridLayout
        LeftPanel          matlab.ui.container.Panel
        LeftGrid           matlab.ui.container.GridLayout
        ProcLabel          matlab.ui.control.Label
        ProcDropDown       matlab.ui.control.DropDown
        EndButtonGroup     matlab.ui.container.ButtonGroup
        SinPvCheck         matlab.ui.control.CheckBox
        RightGrid          matlab.ui.container.GridLayout
        TabGroup           matlab.ui.container.TabGroup
        TabResults         matlab.ui.container.Tab
        TabReadme          matlab.ui.container.Tab
        ReadmeText         matlab.ui.control.TextArea
        EndA               matlab.ui.control.RadioButton
        EndB               matlab.ui.control.RadioButton
        EndC               matlab.ui.control.RadioButton
        ParamPanel         matlab.ui.container.Panel
        ParamGrid          matlab.ui.container.GridLayout
        DLabel             matlab.ui.control.Label
        DField             matlab.ui.control.NumericEditField
        Le0Label           matlab.ui.control.Label
        Le0Field           matlab.ui.control.NumericEditField
        X0Label            matlab.ui.control.Label
        X0Field            matlab.ui.control.NumericEditField
        TetaLabel          matlab.ui.control.Label
        TetaField          matlab.ui.control.NumericEditField
        fLabel             matlab.ui.control.Label
        fField             matlab.ui.control.NumericEditField
        RvLabel            matlab.ui.control.Label
        RvField            matlab.ui.control.NumericEditField
        KLabel             matlab.ui.control.Label
        KField             matlab.ui.control.NumericEditField
        StopLabel          matlab.ui.control.Label
        StopField          matlab.ui.control.NumericEditField
        SolverLabel        matlab.ui.control.Label
        SolverDropDown     matlab.ui.control.DropDown
        ButtonGrid         matlab.ui.container.GridLayout
        SimButton          matlab.ui.control.Button
        ResetButton        matlab.ui.control.Button
        ExportButton       matlab.ui.control.Button
        ExportDataButton   matlab.ui.control.Button
        MetricsPanel       matlab.ui.container.Panel
        MetricsGrid        matlab.ui.container.GridLayout
        P1MinLabel         matlab.ui.control.Label
        VMaxLabel          matlab.ui.control.Label
        LeFinalLabel       matlab.ui.control.Label
        CpuLabel           matlab.ui.control.Label
        StatusGrid         matlab.ui.container.GridLayout
        StatusLamp         matlab.ui.control.Lamp
        StatusLabel        matlab.ui.control.Label
        RightPanel         matlab.ui.container.Panel
        AxesGrid           matlab.ui.container.GridLayout
        AxP1               matlab.ui.control.UIAxes
        AxVe               matlab.ui.control.UIAxes
        AxLe               matlab.ui.control.UIAxes
    end

    properties (Access = private)
        ModelName = 'VaciadoBifasicoSimple'
        % Parametros por proceso: {valor, limites}; los defaults son los del modelo .slx
        Defs = struct( ...
            'Vaciado', struct('Model', 'VaciadoBifasicoSimple', 'Stop', 2000, ...
                'D', {{0.2566 [0.01 5]}}, 'Le0', {{992.496 [1 5000]}}, 'X0', {{1.4131 [0.001 100]}}, ...
                'Teta', {{0.074 [-1.5708 1.5708]}}, 'f', {{0.0108 [0 1]}}, 'Rv', {{14.1149 [0 1000]}}, 'K', {{1.3216 [0.5 3]}}), ...
            'Llenado', struct('Model', 'AirInTwoPipes', 'Stop', 450, ...
                'D', {{0.3 [0.01 5]}}, 'Le0', {{990 [1 5000]}}, 'X0', {{5 [0 1000]}}, ...
                'Teta', {{-0.1 [-1 1]}}, 'f', {{0.1 [-1 1]}}, 'Rv', {{0 [0 1000]}}, 'K', {{24 [0 1000]}}))
        Patm = 101.325            % kPa
        Result = struct('t', [], 've', [], 'P1', [], 'Le', [])  % P1 en kPa
    end

    methods (Access = private)

        function setStatus(app, color, msg)
            app.StatusLamp.Color = color;
            app.StatusLabel.Text = msg;
            drawnow;
        end

        function setField(~, fld, c)
            fld.Limits = [-Inf Inf];
            fld.Value = c{1};
            fld.Limits = c{2};
        end

        function applyProcess(app)
            proc = app.ProcDropDown.Value;
            d = app.Defs.(proc);
            app.ModelName = d.Model;
            setField(app, app.DField, d.D);
            setField(app, app.Le0Field, d.Le0);
            setField(app, app.X0Field, d.X0);
            setField(app, app.TetaField, d.Teta);
            setField(app, app.fField, d.f);
            setField(app, app.RvField, d.Rv);
            setField(app, app.KField, d.K);
            app.StopField.Value = d.Stop;
            isVac = strcmp(proc, 'Vaciado');
            if isVac
                lab = {'D (m)', 'Le0 (m)', 'X0 (m)', 'Teta (rad)', 'f (-)', 'Rv (-)', 'K (-)'};
            else   % AirInTwoPipes: se reutilizan los campos con los nombres del modelo
                lab = {'D (m)', 'Lb10 (m)', 'H_d (m)', 'pend1 (-)', 'pend2 (-)', '', 'K (-)'};
            end
            lbls = {app.DLabel, app.Le0Label, app.X0Label, app.TetaLabel, app.fLabel, app.RvLabel, app.KLabel};
            for k = 1:7, lbls{k}.Text = lab{k}; end
            app.RvLabel.Visible = isVac;
            app.RvField.Visible = isVac;
            app.SinPvCheck.Value = false;
            app.SinPvCheck.Visible = isVac;
            app.LeftGrid.RowHeight{5} = 24 * isVac;
            app.SolverDropDown.Value = 'ode45';
            app.SolverDropDown.Visible = isVac;
            app.EndButtonGroup.Visible = isVac;
            app.EndButtonGroup.SelectedObject = app.EndA;
            if isVac
                app.LeftGrid.RowHeight{2} = 104;
                app.UIFigure.Name = 'Vaciado bifásico - columna rígida';
                title(app.AxP1, 'Presión absoluta de la bolsa de aire P_1(t)');
                title(app.AxVe, 'Velocidad de oscilación de la masa v_e(t)');
                title(app.AxLe, 'Longitud de la columna de agua L_e(t)');
                ylabel(app.AxP1, 'P_1 (kPa)'); ylabel(app.AxVe, 'v_e (m/s)'); ylabel(app.AxLe, 'L_e (m)');
            else
                app.LeftGrid.RowHeight{2} = 0;
                app.UIFigure.Name = 'Llenado bifásico - columna rígida';
                title(app.AxP1, 'Presión p_1(t) - llenado (AirInTwoPipes)');
                title(app.AxVe, 'Velocidad de llenado v(t) y de bloqueo v_1(t)');
                title(app.AxLe, 'Posición del frente x_1(t) y longitud L(t)');
                ylabel(app.AxP1, 'p_1 (m.c.a.)'); ylabel(app.AxVe, 'v (m/s)'); ylabel(app.AxLe, 'x_1, L (m)');
            end
            clearPlots(app);
            setStatus(app, [0.6 0.6 0.6], 'Listo');
        end

        function clearPlots(app)
            cla(app.AxP1); cla(app.AxVe); cla(app.AxLe);
            app.Result = struct('t', [], 've', [], 'P1', [], 'Le', []);
            app.P1MinLabel.Text = 'P1 mín: —';
            app.VMaxLabel.Text = 'v máx: —';
            app.LeFinalLabel.Text = 'Le final: —';
            app.CpuLabel.Text = 'Tiempo de cómputo: —';
            app.ExportButton.Enable = 'off';
            app.ExportDataButton.Enable = 'off';
        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clearPlots(app);
            setStatus(app, [0.6 0.6 0.6], 'Listo');
        end

        % Button pushed function: SimButton
        function SimButtonPushed(app, event)
            app.SimButton.Enable = 'off';
            cleanup = onCleanup(@() set(app.SimButton, 'Enable', 'on'));
            setStatus(app, [0.95 0.65 0.1], 'Simulando…');
            try
                appDir = fileparts(mfilename('fullpath'));
                modelFile = fullfile(appDir, [app.ModelName '.slx']);
                if ~isfile(modelFile)
                    error('No se encontró el modelo: %s', modelFile);
                end
                in = Simulink.SimulationInput(modelFile);
                if strcmp(app.ProcDropDown.Value, 'Vaciado')
                    in = setVariable(in, 'D',        app.DField.Value,    'Workspace', app.ModelName);
                    in = setVariable(in, 'K',        app.KField.Value,    'Workspace', app.ModelName);
                    in = setVariable(in, 'Le0',      app.Le0Field.Value,  'Workspace', app.ModelName);
                    in = setVariable(in, 'Rv',       app.RvField.Value,   'Workspace', app.ModelName);
                    in = setVariable(in, 'Teta_RAD', app.TetaField.Value, 'Workspace', app.ModelName);
                    in = setVariable(in, 'X0',       app.X0Field.Value,   'Workspace', app.ModelName);
                    in = setVariable(in, 'f',        app.fField.Value,    'Workspace', app.ModelName);
                else
                    in = setVariable(in, 'D',     app.DField.Value,    'Workspace', app.ModelName);
                    in = setVariable(in, 'K',     app.KField.Value,    'Workspace', app.ModelName);
                    in = setVariable(in, 'Lb10',  app.Le0Field.Value,  'Workspace', app.ModelName);
                    in = setVariable(in, 'H_d',   app.X0Field.Value,   'Workspace', app.ModelName);
                    in = setVariable(in, 'pend1', app.TetaField.Value, 'Workspace', app.ModelName);
                    in = setVariable(in, 'pend2', app.fField.Value,    'Workspace', app.ModelName);
                end
                if strcmp(app.ProcDropDown.Value, 'Vaciado')
                    in = setModelParameter(in, 'StopTime', num2str(app.StopField.Value), ...
                        'Solver', app.SolverDropDown.Value, ...
                        'ZeroCrossAlgorithm', 'Adaptive');
                else   % llenado: se conserva el solver propio del modelo
                    in = setModelParameter(in, 'StopTime', num2str(app.StopField.Value));
                end
                tic;
                out = sim(in);
                cpu = toc;

                isVac = strcmp(app.ProcDropDown.Value, 'Vaciado');
                if isVac
                    ts = out.ve_P1_Le;
                    t  = ts.Time(:);
                    d  = ts.Data;
                    app.Result = struct('t', t, 've', d(:,1), 'P1', d(:,2)/1000, 'Le', d(:,3));
                    pu = 'kPa';
                else
                    % Predictors_responce = [T Lb1 Hd Ab Cb K v v1 x1 L P]
                    ts = out.Predictors_responce;
                    t  = ts.Time(:);
                    d  = ts.Data;
                    app.Result = struct('t', t, 've', d(:,7), 'P1', d(:,11), 'Le', d(:,10));
                    pu = 'm.c.a.';
                end

                clearAxes = {app.AxP1, app.AxVe, app.AxLe};
                cellfun(@cla, clearAxes);
                plot(app.AxP1, t, app.Result.P1, 'Color', [0.08 0.35 0.72], 'LineWidth', 1.4);
                if isVac
                    yline(app.AxP1, app.Patm, '--', 'P_{atm}', 'Color', [0.85 0.2 0.2], ...
                        'LabelHorizontalAlignment', 'left');
                    if ~app.SinPvCheck.Value
                        hPv = yline(app.AxP1, 2337/1000, '--', 'Color', [0.45 0.45 0.45], 'LineWidth', 1.2, ...
                            'DisplayName', 'Pv');
                        legend(app.AxP1, hPv, 'Location', 'northeast');
                    end
                    plot(app.AxVe, t, app.Result.ve, 'Color', [0.85 0.35 0.05], 'LineWidth', 1.4);
                    plot(app.AxLe, t, app.Result.Le, 'Color', [0.12 0.62 0.32], 'LineWidth', 1.4);
                else
                    plot(app.AxVe, t, d(:,7), 'Color', [0.85 0.35 0.05], 'LineWidth', 1.4, 'DisplayName', 'v');
                    hold(app.AxVe, 'on');
                    plot(app.AxVe, t, d(:,8), 'Color', [0.45 0.25 0.65], 'LineWidth', 1.4, 'DisplayName', 'v_1');
                    hold(app.AxVe, 'off');
                    legend(app.AxVe, 'Location', 'northeast');
                    plot(app.AxLe, t, d(:,9), 'Color', [0.12 0.62 0.32], 'LineWidth', 1.4, 'DisplayName', 'x_1');
                    hold(app.AxLe, 'on');
                    plot(app.AxLe, t, d(:,10), 'Color', [0.2 0.2 0.2], 'LineWidth', 1.4, 'DisplayName', 'L');
                    hold(app.AxLe, 'off');
                    legend(app.AxLe, 'Location', 'northeast');
                end
                xlim(app.AxP1, [t(1) t(end)]);  % linkaxes deja XLim en manual [0 1]; se fija al tramo simulado

                app.P1MinLabel.Text   = sprintf('P1 mín: %.6g %s', min(app.Result.P1), pu);
                app.VMaxLabel.Text    = sprintf('v máx: %.6g m/s', max(abs(app.Result.ve)));
                app.LeFinalLabel.Text = sprintf('Le final: %.6g m', app.Result.Le(end));
                app.CpuLabel.Text     = sprintf('Tiempo de cómputo: %.2f s', cpu);
                app.ExportButton.Enable = 'on';
                app.ExportDataButton.Enable = 'on';
                setStatus(app, [0.2 0.7 0.3], 'Simulación terminada');
            catch ME
                setStatus(app, [0.85 0.15 0.15], 'Error en la simulación');
                uialert(app.UIFigure, ME.message, 'Error de simulación');
            end
        end

        % Value changed function: SinPvCheck
        function SinPvCheckValueChanged(app, event)
            if app.SinPvCheck.Value
                app.ModelName = 'VaciadoBifasicoSimple_sinPv';
            else
                app.ModelName = 'VaciadoBifasicoSimple';
            end
        end

        % Value changed function: ProcDropDown
        function ProcDropDownValueChanged(app, event)
            applyProcess(app);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            applyProcess(app);
        end

        % Button pushed function: ExportDataButton
        function ExportDataButtonPushed(app, event)
            [file, folder] = uiputfile({'*.csv', 'CSV'; '*.xlsx', 'Excel (xlsx)'}, ...
                'Exportar datos', [lower(app.ProcDropDown.Value) '_bifasico.csv']);
            figure(app.UIFigure);
            if isequal(file, 0), return; end
            try
                r = app.Result;
                if strcmp(app.ProcDropDown.Value, 'Vaciado')
                    nm = {'t_s', 've_m_s', 'P1_kPa', 'Le_m'};
                else
                    nm = {'t_s', 'v_m_s', 'p1_mca', 'L_m'};
                end
                T = table(r.t, r.ve, r.P1, r.Le, 'VariableNames', nm);
                writetable(T, fullfile(folder, file));
                setStatus(app, [0.2 0.7 0.3], ['Exportado: ' file]);
            catch ME
                setStatus(app, [0.85 0.15 0.15], 'Error al exportar');
                uialert(app.UIFigure, ME.message, 'Error de exportación');
            end
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            [file, folder] = uiputfile({'*.png', 'PNG (600 dpi)'; '*.svg', 'SVG (vectorial)'; '*.pdf', 'PDF'}, ...
                'Exportar gráficas', [lower(app.ProcDropDown.Value) '_bifasico.png']);
            figure(app.UIFigure);
            if isequal(file, 0), return; end
            try
                r = app.Result;
                f = figure('Visible', 'off', 'Color', 'w', 'Position', [100 100 800 900]);
                fcleanup = onCleanup(@() delete(f));
                tl = tiledlayout(f, 3, 1, 'TileSpacing', 'compact');
                ax = nexttile(tl);
                plot(ax, r.t, r.P1, 'Color', [0.08 0.35 0.72], 'LineWidth', 1.4); hold(ax, 'on');
                if strcmp(app.ProcDropDown.Value, 'Vaciado'), yline(ax, app.Patm, '--', 'P_{atm}', 'Color', [0.85 0.2 0.2]); end
                ylabel(ax, app.AxP1.YLabel.String); grid(ax, 'on');
                ax = nexttile(tl);
                plot(ax, r.t, r.ve, 'Color', [0.85 0.35 0.05], 'LineWidth', 1.4);
                ylabel(ax, app.AxVe.YLabel.String); grid(ax, 'on');
                ax = nexttile(tl);
                plot(ax, r.t, r.Le, 'Color', [0.12 0.62 0.32], 'LineWidth', 1.4);
                ylabel(ax, app.AxLe.YLabel.String); xlabel(ax, 'Tiempo (s)'); grid(ax, 'on');
                [~, ~, ext] = fileparts(file);
                if strcmpi(ext, '.svg')
                    saveas(f, fullfile(folder, file));   % exportgraphics no escribe SVG
                else
                    exportgraphics(f, fullfile(folder, file), 'Resolution', 600);
                end
                setStatus(app, [0.2 0.7 0.3], ['Exportado: ' file]);
            catch ME
                setStatus(app, [0.85 0.15 0.15], 'Error al exportar');
                uialert(app.UIFigure, ME.message, 'Error de exportación');
            end
        end

    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            txt  = [0.12 0.12 0.14];
            bg   = [0.96 0.96 0.97];
            pbg  = [1 1 1];
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Vaciado bifásico - columna rígida';
            try
                theme(app.UIFigure, 'light');
            catch
            end
            app.MainGrid = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.MainGrid);
            app.LeftGrid = uigridlayout(app.LeftPanel);
            % Create Proceso selector
            app.ProcLabel = uilabel(app.LeftGrid);
            app.ProcLabel.Text = 'Proceso';
            app.ProcLabel.Visible = 'off';
            app.ProcDropDown = uidropdown(app.LeftGrid);
            app.ProcDropDown.Items = {'Vaciado', 'Llenado'};
            app.ProcDropDown.Value = 'Vaciado';
            app.ProcDropDown.ValueChangedFcn = createCallbackFcn(app, @ProcDropDownValueChanged, true);
            app.EndButtonGroup = uibuttongroup(app.LeftGrid);
            app.EndButtonGroup.Title = 'Tipo de extremo';
            app.EndA = uiradiobutton(app.EndButtonGroup);
            app.EndA.Text = 'A  Cerrado (bolsa atrapada)';
            app.EndA.Value = true;
            app.EndB = uiradiobutton(app.EndButtonGroup);
            app.EndB.Text = 'B  Ventosa (pendiente de modelo)';
            app.EndB.Enable = 'off';
            app.EndC = uiradiobutton(app.EndButtonGroup);
            app.EndC.Text = 'C  Abierto (pendiente de modelo)';
            app.EndC.Enable = 'off';
            app.ParamPanel = uipanel(app.LeftGrid);
            app.ParamPanel.Title = 'Parámetros';
            app.ParamGrid = uigridlayout(app.ParamPanel);
            [app.DLabel,    app.DField]    = app.addParam('D (m)',          0.2566,   [0.01 5],      1, txt, pbg);
            [app.Le0Label,  app.Le0Field]  = app.addParam('Le0 (m)',       992.496,  [1 5000],      2, txt, pbg);
            [app.X0Label,   app.X0Field]   = app.addParam('X0 (m)',        1.4131,   [0.001 100],   3, txt, pbg);
            [app.TetaLabel, app.TetaField] = app.addParam('Teta (rad)',    0.074,    [-1.5708 1.5708], 4, txt, pbg);
            [app.fLabel,    app.fField]    = app.addParam('f (-)',         0.0108,   [0 1],         5, txt, pbg);
            [app.RvLabel,   app.RvField]   = app.addParam('Rv (-)',        14.1149,  [0 1000],      6, txt, pbg);
            [app.KLabel,    app.KField]    = app.addParam('K (-)',         1.3216,   [0.5 3],       7, txt, pbg);
            [app.StopLabel, app.StopField] = app.addParam('StopTime (s)',  2000,     [1 100000],    8, txt, pbg);
            app.SolverLabel = uilabel(app.LeftGrid);
            app.SolverLabel.Text = 'Solver';
            app.SolverLabel.Visible = 'off';
            app.SolverDropDown = uidropdown(app.LeftGrid);
            app.SolverDropDown.Items = {'ode45', 'ode15s', 'ode23t'};
            app.SolverDropDown.Value = 'ode45';
            app.SinPvCheck = uicheckbox(app.LeftGrid);
            app.SinPvCheck.Text = 'Sin tope de Pv (caso crítico puro)';
            app.SinPvCheck.Value = false;
            app.SinPvCheck.ValueChangedFcn = createCallbackFcn(app, @SinPvCheckValueChanged, true);
            app.ButtonGrid = uigridlayout(app.LeftGrid);
            app.SimButton = uibutton(app.ButtonGrid, 'push');
            app.SimButton.ButtonPushedFcn = createCallbackFcn(app, @SimButtonPushed, true);
            app.SimButton.Text = 'Simular';
            app.ResetButton = uibutton(app.ButtonGrid, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Restablecer';
            app.ExportButton = uibutton(app.ButtonGrid, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = '';
            app.ExportButton.Tooltip = 'Exportar gráficas (PNG, SVG o PDF)';
            ic = 255 * ones(20, 20, 3, 'uint8');          % icono: gráfica de barras
            ic(18:19, 2:19, :) = 60;  ic(2:19, 2:3, :) = 60;
            ic(11:17, 5:7, :) = repmat(reshape(uint8([20 90 184]), 1, 1, 3), 7, 3);
            ic(6:17, 9:11, :) = repmat(reshape(uint8([217 89 13]), 1, 1, 3), 12, 3);
            ic(9:17, 13:15, :) = repmat(reshape(uint8([31 158 82]), 1, 1, 3), 9, 3);
            app.ExportButton.Icon = ic;
            app.ExportButton.Enable = 'off';
            app.ExportDataButton = uibutton(app.ButtonGrid, 'push');
            app.ExportDataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDataButtonPushed, true);
            app.ExportDataButton.Text = '';
            app.ExportDataButton.Tooltip = 'Exportar datos (CSV o XLSX)';
            app.ExportDataButton.Enable = 'off';
            ic = 255 * ones(20, 20, 3, 'uint8');          % icono: tabla
            ic([3 8 13 18], 2:19, :) = 60;  ic(3:18, [2 8 14 19], :) = 60;
            ic(4:7, 3:7, :) = repmat(reshape(uint8([180 200 225]), 1, 1, 3), 4, 5);
            app.ExportDataButton.Icon = ic;
            app.MetricsPanel = uipanel(app.LeftGrid);
            app.MetricsPanel.Title = 'Métricas';
            app.MetricsGrid = uigridlayout(app.MetricsPanel);
            app.P1MinLabel = uilabel(app.MetricsGrid);
            app.VMaxLabel = uilabel(app.MetricsGrid);
            app.LeFinalLabel = uilabel(app.MetricsGrid);
            app.CpuLabel = uilabel(app.MetricsGrid);
            app.StatusGrid = uigridlayout(app.LeftGrid);
            app.StatusLamp = uilamp(app.StatusGrid);
            app.StatusLabel = uilabel(app.StatusGrid);
            app.StatusLabel.Text = 'Listo';
            app.RightPanel = uipanel(app.MainGrid);
            app.RightGrid = uigridlayout(app.RightPanel, [1 1]);
            app.TabGroup = uitabgroup(app.RightGrid);
            app.TabResults = uitab(app.TabGroup);
            app.TabResults.Title = 'Resultados';
            app.TabReadme = uitab(app.TabGroup);
            app.TabReadme.Title = 'README';
            readmeGrid = uigridlayout(app.TabReadme, [1 1]);
            readmeGrid.BackgroundColor = bg;
            app.ReadmeText = uitextarea(readmeGrid);
            app.ReadmeText.Editable = 'off';
            app.ReadmeText.Value = {'TRANSITORIO BIFÁSICO - columna rígida con bolsa de aire', ...
                'Autor: Aiken H. Ortega-Heredia (UPV / Institución Universitaria Mayor de Cartagena)', ...
                '', ...
                'PROPÓSITO', ...
                'Simula dos transitorios en tuberías a presión con bolsa de aire atrapada, cada uno con su modelo Simulink:', ...
                '- Vaciado (extremo cerrado, tipo A): modelo VaciadoBifasicoSimple. Columna rígida acoplada a una bolsa de aire politrópica.', ...
                '- Llenado: modelo AirInTwoPipes (dos tuberías con pendientes pend1 y pend2).', ...
                '', ...
                'USO', ...
                '1. Elegir el proceso en el selector Proceso. Los parámetros cambian a los valores por defecto del modelo.', ...
                '2. Ajustar parámetros y StopTime. Pulsar Simular.', ...
                '3. Revisar las tres gráficas (zoom y pan en cada eje) y las métricas.', ...
                '4. El botón con icono de gráfica guarda las tres gráficas en PNG (600 dpi), SVG o PDF. El botón con icono de tabla guarda los datos en CSV o XLSX. Restablecer vuelve a los valores por defecto.', ...
                '', ...
                'CASILLA SIN TOPE DE PV (CASO CRÍTICO PURO)', ...
                'Solo en Vaciado. Marcada, usa VaciadoBifasicoSimple_sinPv, el mismo modelo sin el tope por presión de vapor. Desmarcada, usa el modelo con tope.', ...
                '', ...
                'PARÁMETROS DE VACIADO', ...
                'D (diámetro, m), Le0 (longitud inicial de la columna, m), X0 (m), Teta (ángulo, rad), f (factor de fricción), Rv (-), K (exponente politrópico).', ...
                '', ...
                'PARÁMETROS DE LLENADO (AirInTwoPipes)', ...
                'D, K, Lb10, H_d, pend1, pend2, con los nombres del espacio de trabajo del modelo. Las unidades (m, m.c.a.) se suponen y deben confirmarse con el modelo.', ...
                '', ...
                'SALIDAS', ...
                'Vaciado: ve_P1_Le = [v_e, P_1, L_e]. Llenado: Predictors_responce = [T Lb1 Hd Ab Cb K v v1 x1 L P].', ...
                '', ...
                'REQUISITOS', ...
                'MATLAB R2026b o posterior con Simulink. Los modelos .slx viajan dentro del paquete y no se modifican.'};
            app.AxesGrid = uigridlayout(app.TabResults);
            app.AxP1 = uiaxes(app.AxesGrid);
            title(app.AxP1, 'Presión absoluta de la bolsa de aire P_1(t)');
            ylabel(app.AxP1, 'P_1 (kPa)');
            app.AxVe = uiaxes(app.AxesGrid);
            title(app.AxVe, 'Velocidad de oscilación de la masa v_e(t)');
            ylabel(app.AxVe, 'v_e (m/s)');
            app.AxLe = uiaxes(app.AxesGrid);
            title(app.AxLe, 'Longitud de la columna de agua L_e(t)');
            ylabel(app.AxLe, 'L_e (m)');
            xlabel(app.AxLe, 'Tiempo (s)');
            for ax = [app.AxP1 app.AxVe app.AxLe]
                ax.Color = [1 1 1];
                ax.XColor = txt; ax.YColor = txt;
                ax.GridColor = [0.15 0.15 0.15];
                grid(ax, 'on'); box(ax, 'on');
            end
            linkaxes([app.AxP1 app.AxVe app.AxLe], 'x');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end

        function [lbl, fld] = addParam(app, text, val, lim, row, txt, pbg)
            lbl = uilabel(app.ParamGrid);
            lbl.Text = text;
            lbl.FontColor = txt;
            lbl.Layout.Row = row;
            lbl.Layout.Column = 1;
            fld = uieditfield(app.ParamGrid, 'numeric');
            fld.Limits = lim;
            fld.Value = val;
            fld.ValueDisplayFormat = '%.6g';
            fld.FontColor = txt;
            fld.BackgroundColor = pbg;
            fld.Layout.Row = row;
            fld.Layout.Column = 2;
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TransitorioBifasico

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end